classdef hannah < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Menu                       matlab.ui.container.Menu
        LoadGeometryMenu           matlab.ui.container.Menu
        Panel_4                    matlab.ui.container.Panel
        SolveButton                matlab.ui.control.Button
        HaltButton                 matlab.ui.control.Button
        Panel_2                    matlab.ui.container.Panel
        KnobDirection              matlab.ui.control.Knob
        IncidentDirectionLabel     matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        R1_Im                      matlab.ui.control.NumericEditField
        SelectRegionDropDownLabel  matlab.ui.control.Label
        SelectRegionDropDown       matlab.ui.control.DropDown
        RegionLabel                matlab.ui.control.Label
        RealLabel                  matlab.ui.control.Label
        ImaginaryLabel             matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        R1_Re                      matlab.ui.control.NumericEditField
        IndicesofRefractionLabel   matlab.ui.control.Label
        PlotSettingsLabel          matlab.ui.control.Label
        btn_plt_type               matlab.ui.container.ButtonGroup
        btn_geom                   matlab.ui.control.RadioButton
        btn_inc                    matlab.ui.control.RadioButton
        btn_sca                    matlab.ui.control.RadioButton
        btn_tot                    matlab.ui.control.RadioButton
        btn_plt_app                matlab.ui.container.ButtonGroup
        btn_real                   matlab.ui.control.RadioButton
        btn_ima                    matlab.ui.control.RadioButton
        btn_abs                    matlab.ui.control.RadioButton
        wavenumber_panel           matlab.ui.container.Panel
        WavelengthnmLabel          matlab.ui.control.Label
        WavenumberSlider           matlab.ui.control.Slider
        spectrum                   matlab.ui.control.Image
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        nregions=0;
        direction = 0;
        wavenumber= 1;
        region_params;
        rcurr;
        chnk_array; 
        clmparams = [];
        hs = [];
        usr_poly;
        poly_handle;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: KnobDirection
        function KnobDirectionValueChanged(app, event)
            value = app.KnobDirection.Value;
            dir = (value - 50)*30/20
            app.direction = dir;
            if (dir <-30)
                app.KnobDirection.Value = 30;
                app.direction = -30;
            end
            if (dir >30)
                app.KnobDirection.Value = 70;
                app.direction = 30;
            end
        end

        % Value changed function: WavenumberSlider
        function WavenumberSliderValueChanged(app, event)
            value = app.WavenumberSlider.Value;
            if (value == 0)
                app.WavenumberSlider.Value = 0.01;
            end
            value = app.WavenumberSlider.Value;
            value
            app.wavenumber = value;
        end

        % Value changed function: SelectRegionDropDown
        function SelectRegionDropDownValueChanged(app, event)
            if (app.nregions > 0)
                delete(app.poly_handle);
                value = app.SelectRegionDropDown.Value;
                app.RegionLabel.Text = value;
                str_lab = value;
                str_lab = erase(str_lab,"Region #");
                rnum = str2num(str_lab);
                app.R1_Re.Value = app.region_params(1,rnum);
                app.R1_Im.Value = app.region_params(2,rnum);
                app.rcurr = rnum;
                app.btn_plt_type.SelectedObject = app.btn_geom;
                app.btn_plt_app.SelectedObject = app.btn_real;
                clparams = app.clmparams;
                [xs] = get_region_pts_gui(app.chnk_array,clparams,rnum);

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   
%%%%%%%%%%%%%%%%%% Delete ME
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   
   
                if (rnum == 1)
                    xs(:,end) = [0;Inf];
                end
                if (rnum == 2)
                    xs(:,end) = [0;-Inf];
                end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   
%%%%%%%%%%%%%%%%%% END Delete ME
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
                
                xs = real(xs);
                
                %app.usr_poly = images.roi.Polygon;
                %app.usr_poly.Parent = app.UIAxes;
                %app.usr_poly.Position = xs';
                if (xs(2,end) ~= Inf && xs(2,end) ~= -Inf)
                    app.usr_poly = polyshape(xs(1,:),xs(2,:));
                    app.poly_handle = plot(app.UIAxes,app.usr_poly);
                    
                elseif (xs(2,end) == Inf)
                    xlim = app.UIAxes.XLim;
                    xdia = xlim(2)-xlim(1);
                    ylim = app.UIAxes.YLim;
                    ydia = ylim(2)-ylim(1);
                    
                    x0 = xlim(2)+xdia/10;
                    y0 = 0;
                    x1 = x0;
                    y1 = ylim(2)+ydia/10;
                    x2 = xlim(1)-xdia/10;
                    y2 = y1;
                    x3 = x2;
                    y3 = 0;
                    xbox = [x0,x1,x2,x3];
                    ybox = [y0,y1,y2,y3];
                    tbox = [xbox;ybox];
                    xs_mod = xs(:,1:(end-1));
                    xs_mod = [tbox(:,3:4),xs_mod,tbox(:,1:3)];
                    app.usr_poly = polyshape(xs_mod(1,:),xs_mod(2,:));
                    app.poly_handle = plot(app.UIAxes,app.usr_poly);
                    app.UIAxes.XLim = xlim;
                    app.UIAxes.YLim = ylim;   
                else
                    xlim = app.UIAxes.XLim;
                    xdia = xlim(2)-xlim(1);
                    ylim = app.UIAxes.YLim;
                    ydia = ylim(2)-ylim(1);
                    
                    x0 = xlim(2)+xdia/10;
                    y0 = 0;
                    x1 = x0;
                    y1 = ylim(1)-ydia/10;
                    x2 = xlim(1)-xdia/10;
                    y2 = y1;
                    x3 = x2;
                    y3 = 0;
                    xbox = [x0,x1,x2,x3];
                    ybox = [y0,y1,y2,y3];
                    tbox = [xbox;ybox];
                    xs_mod = xs(:,1:(end-1));
                    xs_mod = [xs_mod,tbox];
                    app.usr_poly = polyshape(xs_mod(1,:),xs_mod(2,:));
                    app.poly_handle = plot(app.UIAxes,app.usr_poly);
                    app.UIAxes.XLim = xlim;
                    app.UIAxes.YLim = ylim;               
                end              
            end
        end

        % Value changed function: R1_Re
        function R1_ReValueChanged(app, event)
            value = app.R1_Re.Value;
            app.region_params(1,app.rcurr)=value;
        end

        % Value changed function: R1_Im
        function R1_ImValueChanged(app, event)
            value = app.R1_Im.Value;
            if (value <0)
                value = 0;
                app.R1_Im.Value = value;
            end
            app.region_params(2,app.rcurr)=value;
        end

        % Selection changed function: btn_plt_type
        function btn_plt_typeSelectionChanged(app, event)
            delete(app.poly_handle);
            selectedButton = app.btn_plt_type.SelectedObject;
            
        end

        % Selection changed function: btn_plt_app
        function btn_plt_appSelectionChanged(app, event)
            selectedButton = app.btn_plt_app.SelectedObject;
            
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            
        end

        % Button pushed function: HaltButton
        function HaltButtonPushed(app, event)
            
        end

        % Menu selected function: LoadGeometryMenu
        function LoadGeometryMenuSelected(app, event)
            axis_plt = app.UIAxes;
            cla(axis_plt);
            app.chnk_array = [];
            [hs,chnk_arr,params] = load_geom(app.UIAxes);
            app.chnk_array = chnk_arr;
            app.clmparams = params;
            app.hs = hs;
            app.nregions = 5;
            app.region_params = [];
            
            app.SelectRegionDropDown.Items ={};
            Items = [""];
            for i=1:app.nregions
               Items(i) = strcat("Region #",num2str(i));
            end
            
            app.SelectRegionDropDown.Items =Items;
           
            if (app.nregions > 0)
                app.SelectRegionDropDown.Value = Items(1);
                app.RegionLabel.Visible = true;
                reg_params = zeros(2,app.nregions);
                reg_params(1,:) = 1;
                app.region_params = reg_params;
                app.R1_Re.Value = 1;
                app.R1_Im.Value = 0;
                app.R1_Re.Editable = true;
                app.R1_Im.Editable = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.LoadGeometryMenu = uimenu(app.Menu);
            app.LoadGeometryMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadGeometryMenuSelected, true);
            app.LoadGeometryMenu.Text = 'Load Geometry';
            app.Panel_4 = uipanel(app.UIFigure);
            app.SolveButton = uibutton(app.Panel_4, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.HaltButton = uibutton(app.Panel_4, 'push');
            app.HaltButton.ButtonPushedFcn = createCallbackFcn(app, @HaltButtonPushed, true);
            app.HaltButton.Text = 'Halt';
            app.Panel_2 = uipanel(app.UIFigure);
            app.KnobDirection = uiknob(app.Panel_2, 'continuous');
            app.KnobDirection.MajorTicks = [30 40 50 60 70];
            app.KnobDirection.MajorTickLabels = {'-30', '-15', '0', '15', '30'};
            app.KnobDirection.ValueChangedFcn = createCallbackFcn(app, @KnobDirectionValueChanged, true);
            app.KnobDirection.MinorTicks = [30 32.5 35 37.5 40 42.5 45 47.5 50 52.5 55 57.5 60 62.5 65 67.5 70];
            app.KnobDirection.Value = 50;
            app.IncidentDirectionLabel = uilabel(app.Panel_2);
            app.IncidentDirectionLabel.Text = 'Incident Direction';
            app.Panel = uipanel(app.UIFigure);
            app.R1_Im = uieditfield(app.Panel, 'numeric');
            app.R1_Im.ValueChangedFcn = createCallbackFcn(app, @R1_ImValueChanged, true);
            app.R1_Im.Editable = 'off';
            app.SelectRegionDropDownLabel = uilabel(app.Panel);
            app.SelectRegionDropDownLabel.Text = 'Select Region';
            app.SelectRegionDropDown = uidropdown(app.Panel);
            app.SelectRegionDropDown.Items = {};
            app.SelectRegionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectRegionDropDownValueChanged, true);
            app.SelectRegionDropDown.Value = {};
            app.RegionLabel = uilabel(app.Panel);
            app.RegionLabel.Visible = 'off';
            app.RegionLabel.Text = 'Region #1';
            app.RealLabel = uilabel(app.Panel);
            app.RealLabel.Text = 'Real';
            app.ImaginaryLabel = uilabel(app.Panel);
            app.ImaginaryLabel.Text = 'Imaginary';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.R1_Re = uieditfield(app.Panel, 'numeric');
            app.R1_Re.ValueChangedFcn = createCallbackFcn(app, @R1_ReValueChanged, true);
            app.R1_Re.Editable = 'off';
            app.IndicesofRefractionLabel = uilabel(app.UIFigure);
            app.IndicesofRefractionLabel.Text = 'Indices of Refraction';
            app.PlotSettingsLabel = uilabel(app.UIFigure);
            app.PlotSettingsLabel.Text = 'Plot Settings:';
            app.btn_plt_type = uibuttongroup(app.UIFigure);
            app.btn_plt_type.SelectionChangedFcn = createCallbackFcn(app, @btn_plt_typeSelectionChanged, true);
            app.btn_geom = uiradiobutton(app.btn_plt_type);
            app.btn_geom.Text = 'Geometry';
            app.btn_geom.Value = true;
            app.btn_inc = uiradiobutton(app.btn_plt_type);
            app.btn_inc.Text = 'Incident Field';
            app.btn_sca = uiradiobutton(app.btn_plt_type);
            app.btn_sca.Text = 'Scattered Field';
            app.btn_tot = uiradiobutton(app.btn_plt_type);
            app.btn_tot.Text = 'Total Field';
            app.btn_plt_app = uibuttongroup(app.UIFigure);
            app.btn_plt_app.SelectionChangedFcn = createCallbackFcn(app, @btn_plt_appSelectionChanged, true);
            app.btn_real = uiradiobutton(app.btn_plt_app);
            app.btn_real.Text = 'Real Part';
            app.btn_real.Value = true;
            app.btn_ima = uiradiobutton(app.btn_plt_app);
            app.btn_ima.Text = 'Imaginary Part';
            app.btn_abs = uiradiobutton(app.btn_plt_app);
            app.btn_abs.Text = 'Absolute Value';
            app.wavenumber_panel = uipanel(app.UIFigure);
            app.WavelengthnmLabel = uilabel(app.wavenumber_panel);
            app.WavelengthnmLabel.Text = 'Wavelength (nm)';
            app.WavenumberSlider = uislider(app.wavenumber_panel);
            app.WavenumberSlider.Limits = [380 650];
            app.WavenumberSlider.MajorTicks = [380 515 650];
            app.WavenumberSlider.MajorTickLabels = {'380', '515', '650'};
            app.WavenumberSlider.ValueChangedFcn = createCallbackFcn(app, @WavenumberSliderValueChanged, true);
            app.WavenumberSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80];
            app.WavenumberSlider.Value = 380;
            app.spectrum = uiimage(app.wavenumber_panel);
            app.spectrum.ImageSource = 'visible-light-spectrum.png';
            app.UIAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hannah

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end