classdef hannah_bak1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Panel_2                    matlab.ui.container.Panel
        Panel_3                    matlab.ui.container.Panel
        Panel                      matlab.ui.container.Panel
        Region1EditFieldLabel      matlab.ui.control.Label
        R1_Re                      matlab.ui.control.NumericEditField
        R1_Im                      matlab.ui.control.NumericEditField
        Region2EditFieldLabel      matlab.ui.control.Label
        R2_Re                      matlab.ui.control.NumericEditField
        R2_Im                      matlab.ui.control.NumericEditField
        R3_Im                      matlab.ui.control.NumericEditField
        R4_Im                      matlab.ui.control.NumericEditField
        Region3EditFieldLabel      matlab.ui.control.Label
        R3_Re                      matlab.ui.control.NumericEditField
        Region4EditFieldLabel      matlab.ui.control.Label
        R4_Re                      matlab.ui.control.NumericEditField
        Region5Label               matlab.ui.control.Label
        R5_Re                      matlab.ui.control.NumericEditField
        R5_Im                      matlab.ui.control.NumericEditField
        RealLabel                  matlab.ui.control.Label
        ImaginaryLabel             matlab.ui.control.Label
        KnobDirection              matlab.ui.control.Knob
        WavenumberSlider           matlab.ui.control.Slider
        IncidentDirectionLabel     matlab.ui.control.Label
        WavenumberLabel            matlab.ui.control.Label
        IndicesofRefractionLabel   matlab.ui.control.Label
        PlotSettingsLabel          matlab.ui.control.Label
        btn_plt_type               matlab.ui.container.ButtonGroup
        btn_geom                   matlab.ui.control.RadioButton
        btn_inc                    matlab.ui.control.RadioButton
        btn_sca                    matlab.ui.control.RadioButton
        btn_tot                    matlab.ui.control.RadioButton
        btn_plt_app                matlab.ui.container.ButtonGroup
        btn_real                   matlab.ui.control.RadioButton
        btn_ima                    matlab.ui.control.RadioButton
        btn_abs                    matlab.ui.control.RadioButton
        SelectRegionDropDownLabel  matlab.ui.control.Label
        SelectRegionDropDown       matlab.ui.control.DropDown
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        nregions  = 5;
        direction = 0;
        wavenumber= 1;
        region_params = zeros(2,5);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: KnobDirection
        function KnobDirectionValueChanged(app, event)
            value = app.KnobDirection.Value;
            dir = (value - 50)*30/20
            app.direction = dir;
            if (dir <-30)
                app.KnobDirection.Value = 30;
                app.direction = -30;
            end
            if (dir >30)
                app.KnobDirection.Value = 70;
                app.direction = 30;
            end
        end

        % Value changed function: WavenumberSlider
        function WavenumberSliderValueChanged(app, event)
            value = app.WavenumberSlider.Value;
            if (value == 0)
                app.WavenumberSlider.Value = 0.01;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_3 = uipanel(app.Panel_2);
            app.Panel = uipanel(app.UIFigure);
            app.Region1EditFieldLabel = uilabel(app.UIFigure);
            app.Region1EditFieldLabel.Text = 'Region #1';
            app.R1_Re = uieditfield(app.UIFigure, 'numeric');
            app.R1_Re.Value = 1;
            app.R1_Im = uieditfield(app.UIFigure, 'numeric');
            app.Region2EditFieldLabel = uilabel(app.UIFigure);
            app.Region2EditFieldLabel.Text = 'Region #2';
            app.R2_Re = uieditfield(app.UIFigure, 'numeric');
            app.R2_Re.Value = 1;
            app.R2_Im = uieditfield(app.UIFigure, 'numeric');
            app.R3_Im = uieditfield(app.UIFigure, 'numeric');
            app.R4_Im = uieditfield(app.UIFigure, 'numeric');
            app.Region3EditFieldLabel = uilabel(app.UIFigure);
            app.Region3EditFieldLabel.Text = 'Region #3';
            app.R3_Re = uieditfield(app.UIFigure, 'numeric');
            app.R3_Re.Value = 1;
            app.Region4EditFieldLabel = uilabel(app.UIFigure);
            app.Region4EditFieldLabel.Text = 'Region #4';
            app.R4_Re = uieditfield(app.UIFigure, 'numeric');
            app.R4_Re.Value = 1;
            app.Region5Label = uilabel(app.UIFigure);
            app.Region5Label.Text = 'Region #5';
            app.R5_Re = uieditfield(app.UIFigure, 'numeric');
            app.R5_Re.Value = 1;
            app.R5_Im = uieditfield(app.UIFigure, 'numeric');
            app.RealLabel = uilabel(app.UIFigure);
            app.RealLabel.Text = 'Real';
            app.ImaginaryLabel = uilabel(app.UIFigure);
            app.ImaginaryLabel.Text = 'Imaginary';
            app.KnobDirection = uiknob(app.UIFigure, 'continuous');
            app.KnobDirection.MajorTicks = [30 40 50 60 70];
            app.KnobDirection.MajorTickLabels = {'-30', '-15', '0', '15', '30'};
            app.KnobDirection.ValueChangedFcn = createCallbackFcn(app, @KnobDirectionValueChanged, true);
            app.KnobDirection.MinorTicks = [30 32.5 35 37.5 40 42.5 45 47.5 50 52.5 55 57.5 60 62.5 65 67.5 70];
            app.KnobDirection.Value = 50;
            app.WavenumberSlider = uislider(app.UIFigure);
            app.WavenumberSlider.Limits = [0 80];
            app.WavenumberSlider.MajorTicks = [0 20 40 60 80];
            app.WavenumberSlider.MajorTickLabels = {'0', '20', '40', '60', '80'};
            app.WavenumberSlider.ValueChangedFcn = createCallbackFcn(app, @WavenumberSliderValueChanged, true);
            app.WavenumberSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80];
            app.WavenumberSlider.Value = 1;
            app.IncidentDirectionLabel = uilabel(app.UIFigure);
            app.IncidentDirectionLabel.Text = 'Incident Direction';
            app.WavenumberLabel = uilabel(app.UIFigure);
            app.WavenumberLabel.Text = 'Wavenumber';
            app.IndicesofRefractionLabel = uilabel(app.UIFigure);
            app.IndicesofRefractionLabel.Text = 'Indices of Refraction';
            app.PlotSettingsLabel = uilabel(app.UIFigure);
            app.PlotSettingsLabel.Text = 'Plot Settings:';
            app.btn_plt_type = uibuttongroup(app.UIFigure);
            app.btn_geom = uiradiobutton(app.btn_plt_type);
            app.btn_geom.Text = 'Geometry';
            app.btn_geom.Value = true;
            app.btn_inc = uiradiobutton(app.btn_plt_type);
            app.btn_inc.Text = 'Incident Field';
            app.btn_sca = uiradiobutton(app.btn_plt_type);
            app.btn_sca.Text = 'Scattered Field';
            app.btn_tot = uiradiobutton(app.btn_plt_type);
            app.btn_tot.Text = 'Total Field';
            app.btn_plt_app = uibuttongroup(app.UIFigure);
            app.btn_real = uiradiobutton(app.btn_plt_app);
            app.btn_real.Text = 'Real Part';
            app.btn_real.Value = true;
            app.btn_ima = uiradiobutton(app.btn_plt_app);
            app.btn_ima.Text = 'Imaginary Part';
            app.btn_abs = uiradiobutton(app.btn_plt_app);
            app.btn_abs.Text = 'Absolute Value';
            app.SelectRegionDropDownLabel = uilabel(app.UIFigure);
            app.SelectRegionDropDownLabel.Text = 'Select Region';
            app.SelectRegionDropDown = uidropdown(app.UIFigure);
            app.SelectRegionDropDown.Items = {'Region 1', 'Region 2', 'Region 3', 'Region 4'};
            app.SelectRegionDropDown.Value = 'Region 1';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hannah_bak1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end